classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        InformacindelpacienteTab        matlab.ui.container.Tab
        ID                              matlab.ui.control.EditField
        IDEditFieldLabel                matlab.ui.control.Label
        FechaDate                       matlab.ui.control.DatePicker
        FechaDatePickerLabel            matlab.ui.control.Label
        EdadEditField                   matlab.ui.control.NumericEditField
        EdadEditFieldLabel              matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        Image2_4                        matlab.ui.control.Image
        RegistrarButton                 matlab.ui.control.Button
        INFORMACINDELPACIENTELabel      matlab.ui.control.Label
        NacionalidadDropDown            matlab.ui.control.DropDown
        NacionalidadDropDownLabel       matlab.ui.control.Label
        SexoDropDown                    matlab.ui.control.DropDown
        SexoDropDownLabel               matlab.ui.control.Label
        NombreyapellidosEditField       matlab.ui.control.EditField
        NombreyapellidosEditFieldLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        AnlisisTab                      matlab.ui.container.Tab
        lamparaDiag                     matlab.ui.control.Lamp
        PacienteNombre                  matlab.ui.control.Label
        IDPacienteLabel                 matlab.ui.control.Label
        usuario                         matlab.ui.control.Label
        UsuarioLabel                    matlab.ui.control.Label
        diagnoResult                    matlab.ui.control.Label
        DIAGNSTICOLabel                 matlab.ui.control.Label
        imagenTik                       matlab.ui.control.Image
        CalidaddeimagenLabel            matlab.ui.control.Label
        borroso                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        CargarimagenButton              matlab.ui.control.Button
        UIAxes_segmentada               matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        CaractersticasTab               matlab.ui.container.Tab
        PacienteNombre_2                matlab.ui.control.Label
        IDPacienteLabel_2               matlab.ui.control.Label
        usuario_2                       matlab.ui.control.Label
        UsuarioLabel_4                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        nnmerodeLabel                   matlab.ui.control.Label
        tablaVariables                  matlab.ui.control.Table
        VARIABLESEXTRADASLabel          matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        HistorialTab                    matlab.ui.container.Tab
        CargarHistorialButton           matlab.ui.control.Button
        usuario_3                       matlab.ui.control.Label
        UsuarioLabel_5                  matlab.ui.control.Label
        PacientesUITable                matlab.ui.control.Table
        Image_3                         matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
    end


    properties (Access = private)
           Usuario1 string % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Usuario1)
            app.usuario.Text = Usuario1;  % ejemplo de uso
            app.usuario_2.Text = Usuario1;
            app.usuario_3.Text = Usuario1;

        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
global imagen1
[filename, pathname] = uigetfile( ...
    {'*.jpg;*.jpeg;*.png;*.tif;*.tiff', 'Imágenes (*.jpg, *.jpeg, *.png, *.tif, *.tiff)'}, ...
    'Selecciona una imagen');
fullpathname = strcat(pathname, filename);

if isequal(filename, 0)
    return; % Usuario canceló la selección
end

imagen1 = imread(fullpathname);
imshow(imagen1, 'Parent', app.UIAxes);

% Llamar a la función que verifica la calidad
[esBuena, mensaje] = verificarCalidad(app, imagen1);
            function [esBuena, mensaje] = verificarCalidad(app, imagen1)
    esBuena = true;
    mensaje = "";

    if size(imagen1,3) == 3
        imagen1 = rgb2gray(imagen1);
    end

    % ---- Blur (Desenfoque) ----
    lap = fspecial('laplacian', 0.2);
    val = var(imfilter(double(imagen1), lap, 'replicate'));

    % Umbral más permisivo (antes era 100)
    umbralDesenfoque = 50;
    brillo = mean(imagen1(:));


    if val < umbralDesenfoque
        mensaje = "La imagen está muy borrosa, sube otra imagen.";
        app.borroso.Text = mensaje;
        esBuena = false;
        return;  % sale si está muy borrosa

    % ---- Brillo (Iluminación) ----
    
    % Umbrales relajados (antes: <50 o >200)
    elseif brillo < 30 || brillo > 230
        mensaje = "La imagen está muy mal iluminada, sube otra imagen.";
        app.borroso.Text = mensaje;
        esBuena = false;
        return;  % sale si está muy mal iluminada
    else

    % ---- Si la imagen es aceptable ----
    app.borroso.Text = "";  % limpia texto anterior
    app.imagenTik.Visible = true;
    end
end


% img = imagen1 % Donde tienes almacenada la imagen cargada
% [numParasitos, segImg] = analizarImagen(img);
% app.parasitos.Text = ['Parásitos: ', num2str(numParasitos)];
% imshow(segImg, 'Parent', app.UIAxes_segmentada); % si quieres mostrar la imagen

img = imagen1 % Donde tienes almacenada la imagen cargada
[estado, numParasitos, numGlobulosBlancos,razonParasitosPorGB,imagenSegmentada,imgGB,imgOtros] = deteccionMalariaRandomForest(fullpathname);
% Crear una fila de resultados
fila = {estado, numParasitos, numGlobulosBlancos, razonParasitosPorGB};

% Escribir en la tabla de la app
app.tablaVariables.Data = fila;

app.diagnoResult.Text=estado;


imshow(imagenSegmentada, 'Parent', app.UIAxes_segmentada); % si quieres mostrar la imagen
% Crear imagen RGB de máscara para GB (azul)
% imgGB_color = cat(3, zeros(size(imgGB)), zeros(size(imgGB)), double(imgGB));
% 
% % Crear imagen RGB de máscara para parásitos (rojo)
% imgOtros_color = cat(3, double(imgOtros), zeros(size(imgOtros)), zeros(size(imgOtros)));

% imshow(imgGB_color, 'Parent', app.UIAxes_imgGB);
% 
% imshow(imgOtros_color, 'Parent', app.UIAxes_imgOtros);

switch estado
    case "Sano"
        app.lamparaDiag.Color = [0, 1, 0]; % Verde
    case "Sospechoso leve"
        app.lamparaDiag.Color = [1, 1, 0]; % Amarillo
    case "Infección moderada"
        app.lamparaDiag.Color = [1, 0.5, 0]; % Naranja
    case "Infección severa"
        app.lamparaDiag.Color = [1, 0, 0]; % Rojo
    otherwise
        app.lamparaDiag.Color = [0.5, 0.5, 0.5]; % Gris por defecto
end


% Supongamos que ya tienes los resultados del análisis
filename = 'pacientes.xlsx';
datos = readtable(filename);

disp(datos.Properties.VariableNames)
id = string(app.ID.Value);% Convertir todo a string por si acaso
id = strtrim(id);

% Buscar fila
idx = find(datos.id == id);

if isempty(idx)
    warndlg("No se encontró el paciente con ID: " + id, "Error");
else
    datos.Diagnostico(idx) = cellstr(estado);
    writetable(datos, filename);
    disp("Guardado:");
disp(datos(idx, {'id', 'Diagnostico'}));
end
          
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
             uialert(app.UIFigure, ...
        "Esta aplicación ha sido fabricada por la empresa Saluvia. Última actualización junio de 2025.", ...
        "Creadores",...
        'Icon', 'info');
        
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            uialert(app.UIFigure, ...
        "Para cualquier duda o pregunta escriba a este correo: saluviasl@gmail.com", ...
        "Información",...
        'Icon', 'info');
        end

        % Callback function
        function usuarioEditFieldValueChanged(app, event)

        end

        % Button pushed function: RegistrarButton
        function RegistrarButtonPushed(app, event)
            Paciente = string(app.NombreyapellidosEditField.Value);
            Edad = string(app.EdadEditField.Value);
            Sexo = string(app.SexoDropDown.Value);
            Nacionalidad = string(app.NacionalidadDropDown.Value);
            Fecha = string(app.FechaDate.Value);
            id = string(app.ID.Value);
           
            
            % Validar campos obligatorios
    if isempty(Paciente) ||  all(isspace(Paciente))|| isempty(id) ||  all(isspace(id)) || isempty(Edad) ||  strcmp(Edad, '0') ||  strcmp(Sexo, 'Seleccionar') || strcmp(Nacionalidad, 'Seleccionar')
               warndlg('Por favor, completa todos los campos obligatorios.','Campos incompletos');
         % Detiene la ejecución del resto del callback
    else
    

            % if app.EdadEditField.Value<16
            %     warndlg('La edad mínima para el uso de esta aplicación es 16 años','Control de edad');
            % else
            
            % Añadir columna de Diagnostico vacía
            Diagnostico = "No registrado";


            T2 = table(Fecha,id,Paciente,Edad,Sexo,Diagnostico);
            filename = 'pacientes.xlsx';
            ruta=strcat(filename);
            existe=exist(ruta);

            if existe==0  %si no existe, escribe la tabla
                writetable(T2,filename,'Sheet',1,'Range','A1')
            % 
            %     data=string(app.dataLabel.Text);
            % errepi=string(app.errepikapenakLabel.Text);
            % kalori=string(app.kaloriakLabel.Text);
            % T2=table(data, Usuario, errepi, kalori);
            % writetable (T2,'datuak.xlsx','Sheet', Usuario,'Range', 'A1')
            app.TabGroup.SelectedTab = app.AnlisisTab;
            app.PacienteNombre.Text=Paciente;
            app.PacienteNombre_2.Text=Paciente;


            else
            % Verificar si el usuario ya existe
            % Nombre=string(app.NombreyapellidosEditField.Value);
            % T4= readtable(filename,'sheet',1,'Range','A:B');
            % k=1;
            % 
            % len=size(T4);
            % 
            % for i=1:len(1)
            %     if strcmp(Nombre,T4.Paciente{i})
            %        warndlg('Este usuario ya existe','Warning!');  
            %        k=k+1;
            %     end
            % end
            % if(k==1)   

            datos = readtable (filename);
            [fila, zutabe] = size(datos);
            zelda = strcat('A', string(fila+2));
            % Agregar nueva fila sin sobrescribir encabezados
            writetable (T2, filename,'Sheet', 1,'Range', zelda, 'WriteVariableNames', false)
            
            % data=string(app.dataLabel.Text);
            % errepi=string(app.errepikapenakLabel.Text);
            % kalori=string(app.kaloriakLabel.Text);
            % 
            % T2=table(data, Usuario, errepi, kalori);
            % writetable (T2,'datuak.xlsx','Sheet', Usuario,'Range', 'A1')

            app.TabGroup.SelectedTab = app.AnlisisTab;
            app.PacienteNombre.Text=id;
            app.PacienteNombre_2.Text=id;
    
            end
        
    end

        end

        % Button pushed function: CargarHistorialButton
        function CargarHistorialButtonPushed(app, event)
            % Leer todos los datos actualizados
filename = 'pacientes.xlsx';
            datos_actualizados = readtable(filename);

% Mostrar en el UITable
app.PacientesUITable.Data = datos_actualizados;
        end

        % Callback function
        function BorrarHistorialButtonPushed(app, event)
        
        end

        % Cell edit callback: PacientesUITable
        function PacientesUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'galdera.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, '1177568.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InformacindelpacienteTab = uitab(app.TabGroup);
            app.InformacindelpacienteTab.Title = 'Información del paciente';
            app.Image4 = uiimage(app.InformacindelpacienteTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'fondo.jpg');
            app.NombreyapellidosEditFieldLabel = uilabel(app.InformacindelpacienteTab);
            app.NombreyapellidosEditFieldLabel.Text = 'Nombre y apellidos';
            app.NombreyapellidosEditField = uieditfield(app.InformacindelpacienteTab, 'text');
            app.SexoDropDownLabel = uilabel(app.InformacindelpacienteTab);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.InformacindelpacienteTab);
            app.SexoDropDown.Items = {'Seleccionar', 'Femenino', 'Masculino', 'Otro'};
            app.SexoDropDown.Value = 'Seleccionar';
            app.NacionalidadDropDownLabel = uilabel(app.InformacindelpacienteTab);
            app.NacionalidadDropDownLabel.Text = 'Nacionalidad';
            app.NacionalidadDropDown = uidropdown(app.InformacindelpacienteTab);
            app.NacionalidadDropDown.Items = {'Seleccionar', 'Afganistán', 'Albania', 'Alemania', 'Andorra', 'Angola', 'Antigua y Barbuda', 'Arabia Saudita', 'Argelia', 'Argentina', 'Armenia', 'Australia', 'Austria', 'Azerbaiyán', 'Bahamas', 'Bangladés', 'Barbados', 'Baréin', 'Bélgica', 'Belice', 'Benín', 'Bielorrusia', 'Birmania', 'Bolivia', 'Bosnia y Herzegovina', 'Botsuana', 'Brasil', 'Brunéi', 'Bulgaria', 'Burkina Faso', 'Burundi', 'Bután', 'Cabo Verde', 'Camboya', 'Camerún', 'Canadá', 'Catar', 'Chad', 'Chile', 'China', 'Chipre', 'Ciudad del Vaticano', 'Colombia', 'Comoras', 'Corea del Norte', 'Corea del Sur', 'Costa de Marfil', 'Costa Rica', 'Croacia', 'Cuba', 'Dinamarca', 'Dominica', 'Ecuador', 'Egipto', 'El Salvador', 'Emiratos Árabes Unidos', 'Eritrea', 'Eslovaquia', 'Eslovenia', 'España', 'Estados Unidos', 'Estonia', 'Etiopía', 'Filipinas', 'Finlandia', 'Fiyi', 'Francia', 'Gabón', 'Gambia', 'Georgia', 'Ghana', 'Granada', 'Grecia', 'Guatemala', 'Guinea', 'Guinea-Bisáu', 'Guinea Ecuatorial', 'Guyana', 'Haití', 'Honduras', 'Hungría', 'India', 'Indonesia', 'Irak', 'Irán', 'Irlanda', 'Islandia', 'Islas Marshall', 'Islas Salomón', 'Israel', 'Italia', 'Jamaica', 'Japón', 'Jordania', 'Kazajistán', 'Kenia', 'Kirguistán', 'Kiribati', 'Kuwait', 'Laos', 'Lesoto', 'Letonia', 'Líbano', 'Liberia', 'Libia', 'Liechtenstein', 'Lituania', 'Luxemburgo', 'Macedonia del Norte', 'Madagascar', 'Malasia', 'Malaui', 'Maldivas', 'Malí', 'Malta', 'Marruecos', 'Mauricio', 'Mauritania', 'México', 'Micronesia', 'Moldavia', 'Mónaco', 'Mongolia', 'Montenegro', 'Mozambique', 'Namibia', 'Nauru', 'Nepal', 'Nicaragua', 'Níger', 'Nigeria', 'Noruega', 'Nueva Zelanda', 'Omán', 'Países Bajos', 'Pakistán', 'Palaos', 'Palestina (como Estado Observador Permanente)', 'Panamá', 'Papúa Nueva Guinea', 'Paraguay', 'Perú', 'Polonia', 'Portugal', 'Reino Unido', 'República Centroafricana', 'República Checa', 'República del Congo', 'República Democrática del Congo', 'República Dominicana', 'Ruanda', 'Rumanía', 'Rusia', 'Samoa', 'San Cristóbal y Nieves', 'San Marino', 'San Vicente y las Granadinas', 'Santa Lucía', 'Santo Tomé y Príncipe', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leona', 'Singapur', 'Siria', 'Somalia', 'Sri Lanka', 'Suazilandia', 'Sudáfrica', 'Sudán', 'Sudán del Sur', 'Suecia', 'Suiza', 'Surinam', 'Tailandia', 'Tanzania', 'Tayikistán', 'Timor Oriental', 'Togo', 'Tonga', 'Trinidad y Tobago', 'Túnez', 'Turkmenistán', 'Turquía', 'Tuvalu', 'Ucrania', 'Uganda', 'Uruguay', 'Uzbekistán', 'Vanuatu', 'Venezuela', 'Vietnam', 'Yemen', 'Yibuti', 'Zambia', 'Zimbabue'};
            app.NacionalidadDropDown.Value = 'Seleccionar';
            app.INFORMACINDELPACIENTELabel = uilabel(app.InformacindelpacienteTab);
            app.INFORMACINDELPACIENTELabel.Text = 'INFORMACIÓN DEL PACIENTE';
            app.RegistrarButton = uibutton(app.InformacindelpacienteTab, 'push');
            app.RegistrarButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarButtonPushed, true);
            app.RegistrarButton.Text = 'Registrar';
            app.Image2_4 = uiimage(app.InformacindelpacienteTab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'ChatGPT_Image_7_may_2025__12_07_49-removebg-preview.png');
            app.Image_4 = uiimage(app.InformacindelpacienteTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Logo_Mondragon_Unibertsitatea.png');
            app.EdadEditFieldLabel = uilabel(app.InformacindelpacienteTab);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.InformacindelpacienteTab, 'numeric');
            app.FechaDatePickerLabel = uilabel(app.InformacindelpacienteTab);
            app.FechaDatePickerLabel.Text = 'Fecha';
            app.FechaDate = uidatepicker(app.InformacindelpacienteTab);
            app.IDEditFieldLabel = uilabel(app.InformacindelpacienteTab);
            app.IDEditFieldLabel.Text = 'ID';
            app.ID = uieditfield(app.InformacindelpacienteTab, 'text');
            app.AnlisisTab = uitab(app.TabGroup);
            app.AnlisisTab.Title = 'Análisis';
            app.UIAxes = uiaxes(app.AnlisisTab);
            title(app.UIAxes, 'Muestra')
            app.UIAxes_segmentada = uiaxes(app.AnlisisTab);
            title(app.UIAxes_segmentada, 'Muestra segmentada')
            app.CargarimagenButton = uibutton(app.AnlisisTab, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Icon = fullfile(pathToMLAPP, 'cargar imagen.png');
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.Image = uiimage(app.AnlisisTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_Mondragon_Unibertsitatea.png');
            app.Image2 = uiimage(app.AnlisisTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ChatGPT_Image_7_may_2025__12_07_49-removebg-preview.png');
            app.borroso = uilabel(app.AnlisisTab);
            app.borroso.Text = '';
            app.CalidaddeimagenLabel = uilabel(app.AnlisisTab);
            app.CalidaddeimagenLabel.Text = ' Calidad de imagen:';
            app.imagenTik = uiimage(app.AnlisisTab);
            app.imagenTik.Visible = 'off';
            app.imagenTik.ImageSource = fullfile(pathToMLAPP, 'check.png');
            app.DIAGNSTICOLabel = uilabel(app.AnlisisTab);
            app.DIAGNSTICOLabel.Text = ' DIAGNÓSTICO:';
            app.diagnoResult = uilabel(app.AnlisisTab);
            app.diagnoResult.Text = '';
            app.UsuarioLabel = uilabel(app.AnlisisTab);
            app.UsuarioLabel.Text = 'Usuario: ';
            app.usuario = uilabel(app.AnlisisTab);
            app.usuario.Text = '';
            app.IDPacienteLabel = uilabel(app.AnlisisTab);
            app.IDPacienteLabel.Text = 'ID Paciente:';
            app.PacienteNombre = uilabel(app.AnlisisTab);
            app.PacienteNombre.Text = '';
            app.lamparaDiag = uilamp(app.AnlisisTab);
            app.CaractersticasTab = uitab(app.TabGroup);
            app.CaractersticasTab.Title = 'Características';
            app.Image2_2 = uiimage(app.CaractersticasTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'ChatGPT_Image_7_may_2025__12_07_49-removebg-preview.png');
            app.Image_2 = uiimage(app.CaractersticasTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Logo_Mondragon_Unibertsitatea.png');
            app.VARIABLESEXTRADASLabel = uilabel(app.CaractersticasTab);
            app.VARIABLESEXTRADASLabel.Text = ' VARIABLES EXTRAÍDAS';
            app.tablaVariables = uitable(app.CaractersticasTab);
            app.tablaVariables.ColumnName = {'Diagnóstico'; 'n.Parásitos'; 'n.Globulos Blancos'; 'P/GB'};
            app.tablaVariables.RowName = {};
            app.nnmerodeLabel = uilabel(app.CaractersticasTab);
            app.nnmerodeLabel.Text = 'n. (número de)            ';
            app.Label_2 = uilabel(app.CaractersticasTab);
            app.Label_2.Text = '';
            app.UsuarioLabel_4 = uilabel(app.CaractersticasTab);
            app.UsuarioLabel_4.Text = 'Usuario: ';
            app.usuario_2 = uilabel(app.CaractersticasTab);
            app.usuario_2.Text = '';
            app.IDPacienteLabel_2 = uilabel(app.CaractersticasTab);
            app.IDPacienteLabel_2.Text = 'ID Paciente:';
            app.PacienteNombre_2 = uilabel(app.CaractersticasTab);
            app.PacienteNombre_2.Text = '';
            app.HistorialTab = uitab(app.TabGroup);
            app.HistorialTab.Title = 'Historial';
            app.Image2_3 = uiimage(app.HistorialTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'ChatGPT_Image_7_may_2025__12_07_49-removebg-preview.png');
            app.Image_3 = uiimage(app.HistorialTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Logo_Mondragon_Unibertsitatea.png');
            app.PacientesUITable = uitable(app.HistorialTab);
            app.PacientesUITable.ColumnName = {'Fecha'; 'ID'; 'Paciente'; 'Edad'; 'Sexo'; 'Diagnóstico'; 'Parásito'};
            app.PacientesUITable.RowName = {};
            app.PacientesUITable.CellEditCallback = createCallbackFcn(app, @PacientesUITableCellEdit, true);
            app.UsuarioLabel_5 = uilabel(app.HistorialTab);
            app.UsuarioLabel_5.Text = 'Usuario: ';
            app.usuario_3 = uilabel(app.HistorialTab);
            app.usuario_3.Text = '';
            app.CargarHistorialButton = uibutton(app.HistorialTab, 'push');
            app.CargarHistorialButton.ButtonPushedFcn = createCallbackFcn(app, @CargarHistorialButtonPushed, true);
            app.CargarHistorialButton.Text = 'Cargar Historial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end