classdef appRegistro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AtrsButton               matlab.ui.control.Button
        tick                     matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        UsuarioEditField         matlab.ui.control.EditField
        UsuarioEditFieldLabel    matlab.ui.control.Label
        RegistrarButton          matlab.ui.control.Button
        REGISTROLabel            matlab.ui.control.Label
        Image3                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegistrarButton
        function RegistrarButtonPushed(app, event)
             Usuario = string(app.UsuarioEditField.Value);
            Contrase = string(app.ContraseaEditField.Value);
            % Edad = string(app.EdadEditField.Value);
            % Sexo = string(app.SexoDropDown.Value);

            % if app.EdadEditField.Value<16
            %     warndlg('La edad mínima para el uso de esta aplicación es 16 años','Control de edad');
            % else
            
            % % Añadir columna de Diagnostico vacía
            % Diagnostico = "";

            T = table(Usuario, Contrase);
            filename = 'registro.xlsx';
            ruta=strcat(filename);
            existe=exist(ruta);

            if existe==0  %si no existe, escribe la tabla
                writetable(T,filename,'Sheet',1,'Range','A1')
            % 
            %     data=string(app.dataLabel.Text);
            % errepi=string(app.errepikapenakLabel.Text);
            % kalori=string(app.kaloriakLabel.Text);
            % 
            % T2=table(data, Usuario, errepi, kalori);
            % writetable (T2,'datuak.xlsx','Sheet', Usuario,'Range', 'A1')
            appIntroduccion
            delete(app)
           % app.tick.Visible="on";
           % pause(2);
           % app.tick.Visible="off";


            else
            % Verificar si el usuario ya existe
            Contra=string(app.UsuarioEditField.Value);
            T3= readtable(filename,'sheet',1,'Range','A:B');
            k=1;
           
            len=size(T3);

            for i=1:len(1)
                if strcmp(Contra,T3.Usuario{i})
                   warndlg('Este usuario ya existe','Warning!');  
                   k=k+1;
                end
            end
            if(k==1)   

            datos = readtable (filename);
            [fila, zutabe] = size(datos);
            zelda = strcat('A', string(fila+2));
            % Agregar nueva fila sin sobrescribir encabezados
            writetable (T, filename,'Sheet', 1,'Range', zelda, 'WriteVariableNames', false)
            
            % data=string(app.dataLabel.Text);
            % errepi=string(app.errepikapenakLabel.Text);
            % kalori=string(app.kaloriakLabel.Text);
            % 
            % T2=table(data, Usuario, errepi, kalori);
            % writetable (T2,'datuak.xlsx','Sheet', Usuario,'Range', 'A1')
            % app.tick.Visible="on";
            % pause(2);
            % app.tick.Visible="off";
            appIntroduccion
            delete(app)


            end
            end
            
        end

        % Callback function
        function AtrsButtonPushed(app, event)
            appSesion
            delete(app)
        end

        % Button pushed function: AtrsButton
        function AtrsButtonPushed2(app, event)
            appSesion
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '940e74ccb2b5783dae2932aa8db5e1f3_t.jpeg');
            app.REGISTROLabel = uilabel(app.UIFigure);
            app.REGISTROLabel.Text = 'REGISTRO';
            app.RegistrarButton = uibutton(app.UIFigure, 'push');
            app.RegistrarButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarButtonPushed, true);
            app.RegistrarButton.Text = 'Registrar';
            app.UsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ChatGPT_Image_7_may_2025__12_07_49-removebg-preview.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_Mondragon_Unibertsitatea.png');
            app.tick = uiimage(app.UIFigure);
            app.tick.Visible = 'off';
            app.tick.ImageSource = fullfile(pathToMLAPP, 'check.png');
            app.AtrsButton = uibutton(app.UIFigure, 'push');
            app.AtrsButton.ButtonPushedFcn = createCallbackFcn(app, @AtrsButtonPushed2, true);
            app.AtrsButton.Text = 'Atrás';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appRegistro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end