classdef appSesion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        logo                     matlab.ui.control.Image
        mondragon                matlab.ui.control.Image
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        UsuarioEditField         matlab.ui.control.EditField
        UsuarioEditFieldLabel    matlab.ui.control.Label
        INICIARSESINLabel        matlab.ui.control.Label
        ENTRARButton             matlab.ui.control.Button
        REGISTROButton           matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        Image3                   matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, usuarioInput)

        end

        % Button pushed function: ENTRARButton
        function ENTRARButtonPushed(app, event)
Usuario1 = string(app.UsuarioEditField.Value);
Contrase = string(app.ContraseaEditField.Value);

filename = 'registro.xlsx';

if exist(filename, 'file') == 2
    disp("Archivo encontrado.");
    
    T = readtable(filename, 'Sheet', 1, 'Range', 'A:B');
    disp("Tabla cargada:");
    disp(T);

    idx = strcmp(T.Usuario, Usuario1);  % idx es vector lógico
    if any(idx)
        disp("Usuario encontrado.");
        fila = find(idx, 1);  % Encuentra la primera coincidencia
        if strcmp(T.Contrase{fila}, Contrase)
            app2(Usuario1)
            delete(app);  % cierra app1
        else
            disp("Contraseña incorrecta.");
            app.Label.Text = 'Contraseña incorrecta.';
        end
    else
        disp("Usuario no existe.");
        app.Label.Text = 'Este usuario no existe, debes registrarte.';
    end
else
    disp("Archivo no existe.");
    app.Label.Text = 'Este usuario no existe, debes registrarte.';
end
            
          
        end

        % Button pushed function: REGISTROButton
        function REGISTROButtonPushed(app, event)
            appRegistro
            delete(app)

        end

        % Value changed function: UsuarioEditField
        function UsuarioEditFieldValueChanged(app, event)
            
        end

        % Callback function: not associated with a component
        function etiquetaImageClicked(app, event)
           appFolder = fileparts(mfilename('fullpath'));
    imgPath = fullfile(appFolder, 'Etiqueta.png');  % Ruta relativa a la app

    if exist(imgPath, 'file') == 2
        if ispc
            winopen(imgPath);
        elseif ismac
            system(['open "', imgPath, '"']);
        elseif isunix
            system(['xdg-open "', imgPath, '"']);
        end
    else
        uialert(app.UIFigure, ['Archivo no encontrado: ' imgPath], 'Error');
    end
        end

        % Callback function: not associated with a component
        function instruccionesImageClicked(app, event)
            appFolder = fileparts(mfilename('fullpath'));
    pdfPath = fullfile(appFolder, 'instrucciones.pdf');  % Ruta relativa a la app
    
    if exist(pdfPath, 'file') == 2
        if ispc
            winopen(pdfPath);
        elseif ismac
            system(['open "', pdfPath, '"']);
        elseif isunix
            system(['xdg-open "', pdfPath, '"']);
        end
    else
        uialert(app.UIFigure, ['Archivo no encontrado: ' pdfPath], 'Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '940e74ccb2b5783dae2932aa8db5e1f3_t.jpeg');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.REGISTROButton = uibutton(app.UIFigure, 'push');
            app.REGISTROButton.ButtonPushedFcn = createCallbackFcn(app, @REGISTROButtonPushed, true);
            app.REGISTROButton.Text = 'REGISTRO';
            app.ENTRARButton = uibutton(app.UIFigure, 'push');
            app.ENTRARButton.ButtonPushedFcn = createCallbackFcn(app, @ENTRARButtonPushed, true);
            app.ENTRARButton.Text = 'ENTRAR';
            app.INICIARSESINLabel = uilabel(app.UIFigure);
            app.INICIARSESINLabel.Text = 'INICIAR SESIÓN';
            app.UsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioEditField.ValueChangedFcn = createCallbackFcn(app, @UsuarioEditFieldValueChanged, true);
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.mondragon = uiimage(app.UIFigure);
            app.mondragon.ImageSource = fullfile(pathToMLAPP, 'Logo_Mondragon_Unibertsitatea.png');
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'ChatGPT_Image_7_may_2025__12_07_49-removebg-preview.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSesion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end